classdef start_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        RunButton                    matlab.ui.control.Button
        ImportButton                 matlab.ui.control.Button
        LogEditFieldLabel            matlab.ui.control.Label
        LogEditField                 matlab.ui.control.EditField
        notreadyButton               matlab.ui.control.StateButton
        ExportButton                 matlab.ui.control.Button
        BackgroundoptionButtonGroup  matlab.ui.container.ButtonGroup
        ImageButton                  matlab.ui.control.ToggleButton
        VideoButton                  matlab.ui.control.ToggleButton
        Image                        matlab.ui.control.Image
        SelectBackgroundButton       matlab.ui.control.Button
        ModeButtonGroup              matlab.ui.container.ButtonGroup
        foregroundButton             matlab.ui.control.RadioButton
        backgroundButton             matlab.ui.control.RadioButton
        overlayButton                matlab.ui.control.RadioButton
        substituteButton             matlab.ui.control.RadioButton
        StartingFrameEditFieldLabel  matlab.ui.control.Label
        StartingFrameEditField       matlab.ui.control.NumericEditField
        StateLampLabel               matlab.ui.control.Label
        StateLamp                    matlab.ui.control.Lamp
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % EditField set to not editable
            
            global mode;
            global start;
            global dst;
            dst = '';
            start=0;
            app.LogEditField.Enable='on';
            app.LogEditField.Editable='off';
            selectedButton = app.ModeButtonGroup.SelectedObject;
            mode=selectedButton.Text;
            app.StateLamp.Color = ('red');
            
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            % Importbutton for video for segmentation, choose by pop-up
            % window
            global src;
            src = uigetdir;
            if ~isempty(src) && ~(app.substituteButton.Value == 1 && isempty(app.Image.ImageSource))
                app.notreadyButton.Text = 'ready';
            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            % Exportbutton for Exporting the rendered video into a folder
            % and path of choice
            global dst;
            dst = uigetdir;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % Run Button is starting the segmentation and at the end it
            % shows the videoplayer
            
            global src;
            global mode;
            global start;
            global background;
            global dst;
            app.notreadyButton.Text = 'in progress';
            run_gui(src,start,mode,dst,background);
            app.StateLamp.Color = ('green');
            app.notreadyButton.Text = 'done';
            videoplayer(dst);
     
            
        end

        % Button pushed function: SelectBackgroundButton
        function SelectBackgroundButtonPushed(app, event)
            % SelectBackgroundButton lets you choose to import either image
            % or video as background for the rendering
            global background;
            if app.ImageButton.Value==1
                [filename,path]=uigetfile('*.jpg','Find File');
                src = strcat(path,filename);
                if ~ isempty(src)
                    app.Image.ImageSource = src;
                    background=imread(src);
                end
            else
                [filename,path]=uigetfile('*.avi','Find File');
                src = strcat(path,filename);
                if ~ isempty(src)
                    background=vision.VideoFileReader(src,'VideoOutputDataType','uint8');
                    app.Image.ImageSource = background();
                    reset(background);
                end
            end
            if ~isempty(src) && ~(app.substituteButton.Value == 1 && isempty(app.Image.ImageSource))
                app.notreadyButton.Text = 'ready';
            end
        end

        % Value changed function: StartingFrameEditField
        function StartingFrameEditFieldValueChanged(app, event)
            % lets you write the Starting frame where the segmentation is
            % starting with
            global start;
            start = app.StartingFrameEditField.Value;
            if ~ (isnumeric(start) && mod(start,1) == 0 && start >=0)
                app.LogEditField.Value = "Angegebener Startwert ist inkorrekt!";
                return;
            elseif app.LogEditField.Value == "Angegebener Startwert ist inkorrekt!"
                app.LogEditField.Value = '';
            end
        end

        % Selection changed function: ModeButtonGroup
        function ModeButtonGroupSelectionChanged(app, event)
            % lets you choose the mode for the rendering
            global mode
            selectedButton = app.ModeButtonGroup.SelectedObject;
            mode=selectedButton.Text;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.LogEditFieldLabel = uilabel(app.UIFigure);
            app.LogEditFieldLabel.Text = 'Log';
            app.LogEditField = uieditfield(app.UIFigure, 'text');
            app.notreadyButton = uibutton(app.UIFigure, 'state');
            app.notreadyButton.Text = 'not ready';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.BackgroundoptionButtonGroup = uibuttongroup(app.UIFigure);
            app.BackgroundoptionButtonGroup.Title = 'Background option';
            app.ImageButton = uitogglebutton(app.BackgroundoptionButtonGroup);
            app.ImageButton.Text = 'Image';
            app.ImageButton.Value = true;
            app.VideoButton = uitogglebutton(app.BackgroundoptionButtonGroup);
            app.VideoButton.Text = 'Video';
            app.Image = uiimage(app.UIFigure);
            app.SelectBackgroundButton = uibutton(app.UIFigure, 'push');
            app.SelectBackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @SelectBackgroundButtonPushed, true);
            app.SelectBackgroundButton.Text = 'Select Background';
            app.ModeButtonGroup = uibuttongroup(app.UIFigure);
            app.ModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModeButtonGroupSelectionChanged, true);
            app.ModeButtonGroup.Title = 'Mode';
            app.foregroundButton = uiradiobutton(app.ModeButtonGroup);
            app.foregroundButton.Text = 'foreground';
            app.foregroundButton.Value = true;
            app.backgroundButton = uiradiobutton(app.ModeButtonGroup);
            app.backgroundButton.Text = 'background';
            app.overlayButton = uiradiobutton(app.ModeButtonGroup);
            app.overlayButton.Text = 'overlay';
            app.substituteButton = uiradiobutton(app.ModeButtonGroup);
            app.substituteButton.Text = 'substitute';
            app.StartingFrameEditFieldLabel = uilabel(app.UIFigure);
            app.StartingFrameEditFieldLabel.Text = 'Starting Frame';
            app.StartingFrameEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartingFrameEditField.ValueChangedFcn = createCallbackFcn(app, @StartingFrameEditFieldValueChanged, true);
            app.StateLampLabel = uilabel(app.UIFigure);
            app.StateLampLabel.Text = 'State Lamp';
            app.StateLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = start_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end